classdef StabilityMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        RevertMenu                      matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        RunMenu_2                       matlab.ui.container.Menu
        ParametersforRateEquationsPanel  matlab.ui.container.Panel
        NameLabel                       matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        X_label                         matlab.ui.control.Label
        Y_label                         matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        aH                              matlab.ui.control.EditField
        cavitylossrate1nsEditFieldLabel  matlab.ui.control.Label
        kp                              matlab.ui.control.EditField
        carrierlossrate1nsEditFieldLabel  matlab.ui.control.Label
        yn                              matlab.ui.control.EditField
        couplingABEditFieldLabel        matlab.ui.control.Label
        etaAB                           matlab.ui.control.EditField
        couplingBAEditFieldLabel        matlab.ui.control.Label
        etaBA                           matlab.ui.control.EditField
        pumpBEditFieldLabel             matlab.ui.control.Label
        QB                              matlab.ui.control.EditField
        pumpALabel                      matlab.ui.control.Label
        QA                              matlab.ui.control.EditField
        couplingphaseradEditFieldLabel  matlab.ui.control.Label
        theta                           matlab.ui.control.EditField
        detuningradnsEditFieldLabel     matlab.ui.control.Label
        DW                              matlab.ui.control.EditField
        alphaX                          matlab.ui.control.CheckBox
        kappaX                          matlab.ui.control.CheckBox
        gammaX                          matlab.ui.control.CheckBox
        etaABX                          matlab.ui.control.CheckBox
        etaBAX                          matlab.ui.control.CheckBox
        thetaX                          matlab.ui.control.CheckBox
        detuningX                       matlab.ui.control.CheckBox
        QAX                             matlab.ui.control.CheckBox
        QBX                             matlab.ui.control.CheckBox
        alphaY                          matlab.ui.control.CheckBox
        kappaY                          matlab.ui.control.CheckBox
        gammaY                          matlab.ui.control.CheckBox
        etaABY                          matlab.ui.control.CheckBox
        etaBAY                          matlab.ui.control.CheckBox
        thetaY                          matlab.ui.control.CheckBox
        detuningY                       matlab.ui.control.CheckBox
        QAY                             matlab.ui.control.CheckBox
        QBY                             matlab.ui.control.CheckBox
        EqualguidesCheckBox             matlab.ui.control.CheckBox
        EqualpumpsCheckBox              matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        ParameterRangePanel             matlab.ui.container.Panel
        xstart                          matlab.ui.control.EditField
        xend                            matlab.ui.control.EditField
        ystart                          matlab.ui.control.EditField
        yend                            matlab.ui.control.EditField
        xlabel                          matlab.ui.control.Label
        ylabel                          matlab.ui.control.Label
        startLabel                      matlab.ui.control.Label
        endLabel                        matlab.ui.control.Label
        GridpointsEditFieldLabel        matlab.ui.control.Label
        Gridpoints                      matlab.ui.control.EditField
        StabilityMapCoupledModeModelLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        isSaved = true  % All changes have been saved
        isData = false  % There is no data loaded or calculated
        data            % Structure for data
        NGrid = 31      % Default grid size
        
    end
    
    methods (Access = private)
        
        function falsifyX(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaX.Value)
                app.alphaX.Value = false;
                app.aH.Enable = true;
            end
            
            if (app.kappaX.Value)
                app.kappaX.Value = false;
                app.kp.Enable = true;
            end
            
            if (app.gammaX.Value)
                app.gammaX.Value = false;
                app.yn.Enable = true;
            end

            if (app.etaABX.Value)
                app.etaABX.Value = false;
                app.etaAB.Enable = true;
            end
            
            if (app.etaBAX.Value)
                app.etaBAX.Value = false;
                app.etaBA.Enable = true;
            end
            
            if (app.thetaX.Value)
                app.thetaX.Value = false;
                app.theta.Enable = true;
            end
            
            if (app.detuningX.Value)
                app.detuningX.Value = false;
                app.DW.Enable = true;
            end
            
            if (app.QAX.Value)
                app.QAX.Value = false;
                app.QA.Enable = true;
            end
            
            if (app.QBX.Value)
                app.QBX.Value = false;
                app.QB.Enable = true;
            end
            
        end
        
        function falsifyY(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaY.Value)
                app.alphaY.Value = false;
                app.aH.Enable = true;
            end
            
            if (app.kappaY.Value)
                app.kappaY.Value = false;
                app.kp.Enable = true;
            end
            
            if (app.gammaY.Value)
                app.gammaY.Value = false;
                app.yn.Enable = true;
            end

            if (app.etaABY.Value)
                app.etaABY.Value = false;
                app.etaAB.Enable = true;
            end
            
            if (app.etaBAY.Value)
                app.etaBAY.Value = false;
                app.etaBA.Enable = true;
            end
            
            if (app.thetaY.Value)
                app.thetaY.Value = false;
                app.theta.Enable = true;
            end
            
            if (app.detuningY.Value)
                app.detuningY.Value = false;
                app.DW.Enable = true;
            end
            
            if (app.QAY.Value)
                app.QAY.Value = false;
                app.QA.Enable = true;
            end
            
            if (app.QBY.Value)
                app.QBY.Value = false;
                app.QB.Enable = true;
            end
            
        end
        
        
        function loadData(app)
            
            [file,path] = uigetfile('*.mat');

            if not(isequal(file,0))
                
                app.data = load(fullfile(path,file));
                
                % Check data has correct fields
                if (not(isfield(app.data, 'X')) || not(isfield(app.data, 'Y')) || not(isfield(app.data, 'Z'))) 
                    
                    msgbox(['File ' file ' did not contain valid grid data'], 'Invalid grid data','error');
                    
                    % Erase data
                    app.data = 0;
                    
                else
                    
                    app.isData = true;
                    
                    app.plotData();
 
                end
                
            end
            
            % Hack to bring figure back to front
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end
        
        function plotData(app)
            
            if not(app.isData)
                return
            end
            
            % Create colour map
            values = app.data.Z;
            ncols = 11;
            
            maxv = max(values(:));
            minv = min(values(:));
            
            vspan = maxv-minv;
            dv = vspan/(ncols - 1);
            
            sample = minv:dv:maxv;
            neg_mask = (sample <= 0);
            pos_mask = (sample > 0);
            
            c0 = 0.4;
            
            red = (1-c0)*abs(sample.*pos_mask/maxv) + c0*pos_mask;
            green = (1-c0)*abs(sample.*neg_mask/minv) + c0*neg_mask;
            blue = zeros(size(sample));
            
            mymap = transpose([red; green; blue]);
            
            contourf(app.UIAxes, app.data.X, app.data.Y, app.data.Z, 'EdgeColor', 'none')
            colormap(app.UIAxes, mymap)
            colorbar(app.UIAxes)
            app.UIAxes.XLabel.String = '{\it d/a}';
            app.UIAxes.YLabel.String = '{\it P/P_{th}}';
            
  
            % Alternative hack to bring figure back to front
%             figure(app.UIFigure)

            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            
            % Initialize parameters with defaults
            app.aH.Value = num2str(2);           % Linewidth enhancement factor
            app.kp.Value = num2str(326.7974);    % Cavity loss rate 1/(2*tau_p) (1/ns)
            app.yn.Value = num2str(1.0);         % 1/tau_N, where tau_N is the carrier lifetime
            app.etaAB.Value = num2str(1.0);      % Amplitude of coupling coefficient AB
            app.etaBA.Value = num2str(1.0);      % Amplitude of coupling coefficient BA
            app.theta.Value = num2str(0.0);      % Phase of coupling coefficient
            app.DW.Value = num2str(0.0);         % Detuning
            app.QA.Value = num2str(10);          % Normalised pump power in guide A
            app.QB.Value = num2str(10);          % Normalised pump power in guide B
            
            % Set initial map parameters
%             app.etaABX.Value = true;
%             app.QAY.Value = true;
%             app.xlabel.Text = 'coupling (equal)';
%             app.ylabel.Text = 'pump (equal)';
%             app.xstart.Value = num2str(1);
%             app.xend.Value = num2str(5);
%             app.ystart.Value = num2str(1);
%             app.yend.Value = num2str(10);

            app.xlabel.Text = '?';
            app.ylabel.Text = '?';
            
            % Set equal guides
            app.EqualguidesCheckBox.Value = true;
            app.etaBA.Enable = false;
            app.etaBAX.Enable = false;
            app.etaBAY.Enable = false;

            % Set equal pumps
            app.EqualpumpsCheckBox.Value = true;
            app.QB.Enable = false;
            app.QBX.Enable = false;
            app.QBY.Enable = false;
            
            % Grid points
            app.Gridpoints.Value = num2str(app.NGrid);
           
            
        end

        % Value changed function: kappaX
        function kappaXValueChanged(app, event)
            
            value = app.kappaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.kappaX.Value = true;
                app.kp.Enable = false;
                app.xlabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaY.Value == true)
                    
                    app.kappaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.kp.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaX
        function gammaXValueChanged(app, event)
            
            value = app.gammaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.gammaX.Value = true;
                app.yn.Enable = false;
                app.xlabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaY.Value == true)
                    
                    app.gammaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.yn.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: etaABX
        function etaABXValueChanged(app, event)
            
            value = app.etaABX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.etaABX.Value = true;
                app.etaAB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.etaABY.Value == true)
                    
                    app.etaABY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.xlabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.xlabel.Text = 'coupling (AB)';
                    
                end   
                
            else
                
                app.etaAB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: etaBAX
        function etaBAXValueChanged(app, event)
            
            value = app.etaBAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.etaBAX.Value = true;
                app.etaBA.Enable = false;
                
                app.xlabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.etaBAY.Value == true)
                    
                    app.etaBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                
            else
                
                app.etaBA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: thetaX
        function thetaXValueChanged(app, event)
            
            value = app.thetaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.thetaX.Value = true;
                app.theta.Enable = false;
                app.xlabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.thetaY.Value == true)
                    
                    app.thetaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.theta.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningX
        function detuningXValueChanged(app, event)
            
            value = app.detuningX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.detuningX.Value = true;
                app.DW.Enable = false;
                app.xlabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningY.Value == true)
                    
                    app.detuningY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.DW.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: QAX
        function QAXValueChanged(app, event)
            
            value = app.QAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.QAX.Value = true;
                app.QA.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.QAY.Value == true)
                    
                    app.QAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.xlabel.Text = 'pump (equal)';
                    
                else
                    
                    app.xlabel.Text = 'pump (A)';
                    
                end   
                
            else
                
                app.QA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: QBX
        function QBXValueChanged(app, event)
            
            value = app.QBX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.QBX.Value = true;
                app.QB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.QBY.Value == true)
                    
                    app.QBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.QB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Callback function: LoadButton, LoadMenu
        function LoadButtonPushed(app, event)
            
            app.loadData();
            
        end

        % Value changed function: alphaY
        function alphaYValueChanged(app, event)
            
            value = app.alphaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.alphaY.Value = true;
                app.aH.Enable = false;
                app.ylabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaX.Value == true)
                    
                    app.alphaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.aH.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: kappaY
        function kappaYValueChanged(app, event)
            
            value = app.kappaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.kappaY.Value = true;
                app.kp.Enable = false;
                app.ylabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaX.Value == true)
                    
                    app.kappaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.kp.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaY
        function gammaYValueChanged(app, event)
            
            value = app.gammaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.gammaY.Value = true;
                app.yn.Enable = false;
                app.ylabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaX.Value == true)
                    
                    app.gammaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.yn.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: etaABY
        function etaABYValueChanged(app, event)
            
            value = app.etaABY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.etaABY.Value = true;
                app.etaAB.Enable = false;
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.etaBA.Enable = false;
                    
                    app.ylabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.ylabel.Text = 'coupling (AB)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.etaABX.Value == true)
                    
                    app.etaABX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.etaAB.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: etaBAY
        function etaBAYValueChanged(app, event)
            
            value = app.etaBAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.etaBAY.Value = true;
                app.etaBA.Enable = false;
                app.ylabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.etaBAX.Value == true)
                    
                    app.etaBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.etaBA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: thetaY
        function thetaYValueChanged(app, event)
            
            value = app.thetaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.thetaY.Value = true;
                app.theta.Enable = false;
                app.ylabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.thetaX.Value == true)
                    
                    app.thetaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.theta.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningY
        function detuningYValueChanged(app, event)
            
            value = app.detuningY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.detuningY.Value = true;
                app.DW.Enable = false;
                app.ylabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningX.Value == true)
                    
                    app.detuningX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.DW.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: QAY
        function QAYValueChanged(app, event)
            
            value = app.QAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.QAY.Value = true;
                app.QA.Enable = false;
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.ylabel.Text = 'pump (equal)';
                    
                else
                    
                    app.ylabel.Text = 'pump (A)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.QAX.Value == true)
                    
                    app.QAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end

            else
                
                app.QA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: QBY
        function QBYValueChanged(app, event)
            
            value = app.QBY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.QBY.Value = true;
                app.QB.Enable = false;
                app.ylabel.Text = 'pump (B)';
                
                % Check corresponding Y checkbox
                if (app.QBX.Value == true)
                    
                    app.QBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                
            else
                
                app.QB.Enable = true; 
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: aH
        function aHValueChanged(app, event)
            
            value = app.aH.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: kp
        function kpValueChanged(app, event)
            
            value = app.kp.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
        end

        % Value changed function: yn
        function ynValueChanged(app, event)
            
            value = app.yn.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
        end

        % Value changed function: etaAB
        function etaABValueChanged(app, event)
            
            value = app.etaAB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            elseif (app.EqualguidesCheckBox.Value)
                
                app.etaBA.Value = value;     
                
            end
            
        end

        % Value changed function: etaBA
        function etaBAValueChanged(app, event)
            
            value = app.etaBA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: QB
        function QBValueChanged(app, event)
            
            value = app.QB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: QA
        function QAValueChanged(app, event)
            
            value = app.QA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            elseif (app.EqualpumpsCheckBox.Value)
                
                app.QB.Value = value;
                
            end
            
        end

        % Value changed function: theta
        function thetaValueChanged(app, event)
            
            value = app.theta.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: DW
        function DWValueChanged(app, event)
            
            value = app.DW.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: alphaX
        function alphaXValueChanged(app, event)
            
            value = app.alphaX.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyX();
                app.alphaX.Value = true;
                app.aH.Enable = false;
                app.xlabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaY.Value == true)
                    
                    app.alphaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.aH.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: xstart
        function xstartValueChanged(app, event)
            
            value = app.xstart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: xend
        function xendValueChanged(app, event)
            
            value = app.xend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: ystart
        function ystartValueChanged(app, event)
            
            value = app.ystart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: yend
        function yendValueChanged(app, event)
            
            value = app.yend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: Gridpoints
        function GridpointsValueChanged(app, event)
            
            value = app.Gridpoints.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            else
                
                N = floor(str2double(value));
                
                if (N < 2)
                    
                    msgbox('Number of grid points must be an integer greater than 1')
                    
                else
                    
                    app.Gridpoints.Value = num2str(N);
                    
                end               
                
            end
            
        end

        % Value changed function: EqualguidesCheckBox
        function EqualguidesCheckBoxValueChanged(app, event)
            
            value = app.EqualguidesCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.etaBA.Value = app.etaAB.Value;
                
                % Check for checked guides
                if (app.etaBAX.Value)
                    
                    app.etaBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.etaBAY.Value)
                    
                    app.etaBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.etaBA.Enable = false;
                app.etaBAX.Enable = false;
                app.etaBAY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.etaBA.Enable = true;
                app.etaBAX.Enable = true;
                app.etaBAY.Enable = true;
                
            end
            
        end

        % Value changed function: EqualpumpsCheckBox
        function EqualpumpsCheckBoxValueChanged(app, event)
            
            value = app.EqualpumpsCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.QB.Value = app.QA.Value;
                
                % Check for checked guides
                if (app.QBX.Value)
                    
                    app.QBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.QBY.Value)
                    
                    app.QBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.QB.Enable = false;
                app.QBX.Enable = false;
                app.QBY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.QB.Enable = true;
                app.QBX.Enable = true;
                app.QBY.Enable = true;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.RevertMenu = uimenu(app.DataMenu);
            app.RevertMenu.Accelerator = 'z';
            app.RevertMenu.Text = 'Revert';
            app.SaveMenu = uimenu(app.DataMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunMenu_2 = uimenu(app.RunMenu);
            app.RunMenu_2.Accelerator = 'r';
            app.RunMenu_2.Text = 'Run';
            app.ParametersforRateEquationsPanel = uipanel(app.UIFigure);
            app.ParametersforRateEquationsPanel.Title = 'Parameters for Rate Equations';
            app.NameLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.NameLabel.Text = {'Name'; ''};
            app.ValueLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.ValueLabel.Text = {'Value'; ''};
            app.X_label = uilabel(app.ParametersforRateEquationsPanel);
            app.X_label.Text = {'X'; ''};
            app.Y_label = uilabel(app.ParametersforRateEquationsPanel);
            app.Y_label.Text = {'Y'; ''};
            app.alphaEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.aH = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.aH.ValueChangedFcn = createCallbackFcn(app, @aHValueChanged, true);
            app.aH.Tooltip = {'Linewidth enhancement factor'};
            app.cavitylossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.cavitylossrate1nsEditFieldLabel.Text = 'cavity loss rate (1/ns)';
            app.kp = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.kp.ValueChangedFcn = createCallbackFcn(app, @kpValueChanged, true);
            app.kp.Tooltip = {'1/(2*tp), where tp is the photon lifetime'};
            app.carrierlossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.carrierlossrate1nsEditFieldLabel.Text = 'carrier loss rate (1/ns)';
            app.yn = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.yn.ValueChangedFcn = createCallbackFcn(app, @ynValueChanged, true);
            app.yn.Tooltip = {'1/tN, were tN is the carrier lifetime'};
            app.couplingABEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingABEditFieldLabel.Text = 'coupling (AB)';
            app.etaAB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.etaAB.ValueChangedFcn = createCallbackFcn(app, @etaABValueChanged, true);
            app.etaAB.Tooltip = {'amplitude of coupling coefficient (AB)'};
            app.couplingBAEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingBAEditFieldLabel.Text = 'coupling (BA)';
            app.etaBA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.etaBA.ValueChangedFcn = createCallbackFcn(app, @etaBAValueChanged, true);
            app.etaBA.Tooltip = {'amplitude of coupling coefficient (BA)'};
            app.pumpBEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpBEditFieldLabel.Text = 'pump (B)';
            app.QB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.QB.ValueChangedFcn = createCallbackFcn(app, @QBValueChanged, true);
            app.QB.Tooltip = {'normalised pumping in guide B'};
            app.pumpALabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpALabel.Text = 'pump (A)';
            app.QA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.QA.ValueChangedFcn = createCallbackFcn(app, @QAValueChanged, true);
            app.QA.Tooltip = {'normalised pumping in guide A'};
            app.couplingphaseradEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingphaseradEditFieldLabel.Text = 'coupling phase (rad)';
            app.theta = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.theta.ValueChangedFcn = createCallbackFcn(app, @thetaValueChanged, true);
            app.theta.Tooltip = {'phase of coupling coefficient'};
            app.detuningradnsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.detuningradnsEditFieldLabel.Text = 'detuning (rad/ns)';
            app.DW = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.DW.ValueChangedFcn = createCallbackFcn(app, @DWValueChanged, true);
            app.DW.Tooltip = {'laser detuning in rad/ns - N.B. NOT GHz'};
            app.alphaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaX.ValueChangedFcn = createCallbackFcn(app, @alphaXValueChanged, true);
            app.alphaX.Tooltip = {'select this parameter to vary along x axis'};
            app.alphaX.Text = '';
            app.kappaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaX.ValueChangedFcn = createCallbackFcn(app, @kappaXValueChanged, true);
            app.kappaX.Tooltip = {'select this parameter to vary along x axis'};
            app.kappaX.Text = '';
            app.gammaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaX.ValueChangedFcn = createCallbackFcn(app, @gammaXValueChanged, true);
            app.gammaX.Tooltip = {'select this parameter to vary along x axis'};
            app.gammaX.Text = '';
            app.etaABX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.etaABX.ValueChangedFcn = createCallbackFcn(app, @etaABXValueChanged, true);
            app.etaABX.Tooltip = {'select this parameter to vary along x axis'};
            app.etaABX.Text = '';
            app.etaBAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.etaBAX.ValueChangedFcn = createCallbackFcn(app, @etaBAXValueChanged, true);
            app.etaBAX.Tooltip = {'select this parameter to vary along x axis'};
            app.etaBAX.Text = '';
            app.thetaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.thetaX.ValueChangedFcn = createCallbackFcn(app, @thetaXValueChanged, true);
            app.thetaX.Tooltip = {'select this parameter to vary along x axis'};
            app.thetaX.Text = '';
            app.detuningX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningX.ValueChangedFcn = createCallbackFcn(app, @detuningXValueChanged, true);
            app.detuningX.Tooltip = {'select this parameter to vary along x axis'};
            app.detuningX.Text = '';
            app.QAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.QAX.ValueChangedFcn = createCallbackFcn(app, @QAXValueChanged, true);
            app.QAX.Tooltip = {'select this parameter to vary along x axis'};
            app.QAX.Text = '';
            app.QBX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.QBX.ValueChangedFcn = createCallbackFcn(app, @QBXValueChanged, true);
            app.QBX.Tooltip = {'select this parameter to vary along x axis'};
            app.QBX.Text = '';
            app.alphaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaY.ValueChangedFcn = createCallbackFcn(app, @alphaYValueChanged, true);
            app.alphaY.Tooltip = {'select this parameter to vary along y axis'};
            app.alphaY.Text = '';
            app.kappaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaY.ValueChangedFcn = createCallbackFcn(app, @kappaYValueChanged, true);
            app.kappaY.Tooltip = {'select this parameter to vary along y axis'};
            app.kappaY.Text = '';
            app.gammaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaY.ValueChangedFcn = createCallbackFcn(app, @gammaYValueChanged, true);
            app.gammaY.Tooltip = {'select this parameter to vary along y axis'};
            app.gammaY.Text = '';
            app.etaABY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.etaABY.ValueChangedFcn = createCallbackFcn(app, @etaABYValueChanged, true);
            app.etaABY.Tooltip = {'select this parameter to vary along y axis'};
            app.etaABY.Text = '';
            app.etaBAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.etaBAY.ValueChangedFcn = createCallbackFcn(app, @etaBAYValueChanged, true);
            app.etaBAY.Tooltip = {'select this parameter to vary along y axis'};
            app.etaBAY.Text = '';
            app.thetaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.thetaY.ValueChangedFcn = createCallbackFcn(app, @thetaYValueChanged, true);
            app.thetaY.Tooltip = {'select this parameter to vary along y axis'};
            app.thetaY.Text = '';
            app.detuningY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningY.ValueChangedFcn = createCallbackFcn(app, @detuningYValueChanged, true);
            app.detuningY.Tooltip = {'select this parameter to vary along y axis'};
            app.detuningY.Text = '';
            app.QAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.QAY.ValueChangedFcn = createCallbackFcn(app, @QAYValueChanged, true);
            app.QAY.Tooltip = {'select this parameter to vary along y axis'};
            app.QAY.Text = '';
            app.QBY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.QBY.ValueChangedFcn = createCallbackFcn(app, @QBYValueChanged, true);
            app.QBY.Tooltip = {'select this parameter to vary along y axis'};
            app.QBY.Text = '';
            app.EqualguidesCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualguidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualguidesCheckBoxValueChanged, true);
            app.EqualguidesCheckBox.Tooltip = {'When checked, coupling (AB) will be varied with coupling (BA)'};
            app.EqualguidesCheckBox.Text = 'Equal guides';
            app.EqualpumpsCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualpumpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualpumpsCheckBoxValueChanged, true);
            app.EqualpumpsCheckBox.Tooltip = {'When checked, pump (A) will be varied with pump (B)'};
            app.EqualpumpsCheckBox.Text = 'Equal pumps';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterRangePanel = uipanel(app.UIFigure);
            app.ParameterRangePanel.Title = 'Parameter Range';
            app.xstart = uieditfield(app.ParameterRangePanel, 'text');
            app.xstart.ValueChangedFcn = createCallbackFcn(app, @xstartValueChanged, true);
            app.xend = uieditfield(app.ParameterRangePanel, 'text');
            app.xend.ValueChangedFcn = createCallbackFcn(app, @xendValueChanged, true);
            app.ystart = uieditfield(app.ParameterRangePanel, 'text');
            app.ystart.ValueChangedFcn = createCallbackFcn(app, @ystartValueChanged, true);
            app.yend = uieditfield(app.ParameterRangePanel, 'text');
            app.yend.ValueChangedFcn = createCallbackFcn(app, @yendValueChanged, true);
            app.xlabel = uilabel(app.ParameterRangePanel);
            app.xlabel.Text = 'x label';
            app.ylabel = uilabel(app.ParameterRangePanel);
            app.ylabel.Text = 'y label';
            app.startLabel = uilabel(app.ParameterRangePanel);
            app.startLabel.Text = 'start';
            app.endLabel = uilabel(app.ParameterRangePanel);
            app.endLabel.Text = 'end';
            app.GridpointsEditFieldLabel = uilabel(app.ParameterRangePanel);
            app.GridpointsEditFieldLabel.Text = 'Grid points';
            app.Gridpoints = uieditfield(app.ParameterRangePanel, 'text');
            app.Gridpoints.ValueChangedFcn = createCallbackFcn(app, @GridpointsValueChanged, true);
            app.Gridpoints.Tooltip = {'number of grid points to plot on each axis'};
            app.StabilityMapCoupledModeModelLabel = uilabel(app.UIFigure);
            app.StabilityMapCoupledModeModelLabel.Text = 'Stability Map - Coupled Mode Model';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load data from a file'};
            app.LoadButton.Text = 'Load';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.Tooltip = {'Generate the stability map'};
            app.RunButton.Text = 'Run';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save parameters and calculated data'};
            app.SaveDataButton.Text = 'Save Data';
            app.RevertButton = uibutton(app.Panel, 'push');
            app.RevertButton.Tooltip = {'Revert parameters to last run / saved or loaded'};
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StabilityMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end